classdef EntranceModel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        AntennaToEntrancemEditFieldLabel  matlab.ui.control.Label
        AntennaToEntrancemEditField   matlab.ui.control.NumericEditField
        EntranceWidthmEditFieldLabel  matlab.ui.control.Label
        EntranceWidthmEditField       matlab.ui.control.NumericEditField
        AntennaGaindBiEditFieldLabel  matlab.ui.control.Label
        AntennaGaindBiEditField       matlab.ui.control.NumericEditField
        MaximumPowerTransmittedWEditFieldLabel  matlab.ui.control.Label
        MaximumPowerTransmittedWEditField  matlab.ui.control.NumericEditField
        CloseXmEditFieldLabel         matlab.ui.control.Label
        CloseXmEditField              matlab.ui.control.NumericEditField
        FarXmEditFieldLabel           matlab.ui.control.Label
        FarXmEditField                matlab.ui.control.NumericEditField
        MinimumPowerReceivedmWEditFieldLabel  matlab.ui.control.Label
        MinimumumPowerReceivedmWEditField  matlab.ui.control.NumericEditField
        ComputeButton                 matlab.ui.control.Button
        HPBWdegreesEditFieldLabel     matlab.ui.control.Label
        HPBWdegreesEditField          matlab.ui.control.NumericEditField
        FrequencyMHzEditFieldLabel    matlab.ui.control.Label
        FrequencyMHzEditField         matlab.ui.control.NumericEditField
        MaximumAntennaApertureLengthmEditFieldLabel  matlab.ui.control.Label
        MaximumAntennaApertureLengthmEditField  matlab.ui.control.NumericEditField
        MaxDistancewithin3dBrangemEditFieldLabel  matlab.ui.control.Label
        MaxDistancewithin3dBrangemEditField  matlab.ui.control.NumericEditField
        FarFieldThresholdmEditFieldLabel  matlab.ui.control.Label
        FarFieldThresholdmEditField   matlab.ui.control.NumericEditField
        RightPanel                    matlab.ui.container.Panel
        PowerReceivedmWLabel          matlab.ui.control.Label
        PowerReceivedmWEditField      matlab.ui.control.NumericEditField
        XmEditFieldLabel              matlab.ui.control.Label
        XmEditField                   matlab.ui.control.NumericEditField
        YmEditFieldLabel              matlab.ui.control.Label
        YmEditField                   matlab.ui.control.NumericEditField
        DistancetoAntennamEditFieldLabel  matlab.ui.control.Label
        DistancetoAntennamEditField   matlab.ui.control.NumericEditField
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Property % Description
        entranceWidth = 0;
        antenna2entrance = 0;
        antennaGainDBI = 0;
        inputPower = 0;
        frequency = 0;
        hpbwDegrees = 0;
        FFThreshold = 0;
        FFDomain;
        FFCurve;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            cla(app.UIAxes) 
            hold(app.UIAxes,'on')
            
            antennaGain = log2Linear(app.antennaGainDBI);
            antennaHPGain = log2Linear(app.antennaGainDBI-3);
            
            alpha = app.hpbwDegrees / 2;
            theta = 90 - alpha;
            thetaRadians = deg2rad(theta);
            slope = tan(thetaRadians);
            
            closeX = app.antenna2entrance / slope;
            farX = (app.entranceWidth + app.antenna2entrance) / slope;
            
            maximumDistance = sqrt((app.entranceWidth + app.antenna2entrance)^2 + farX^2);
            
            minimumPowerReceived = getPowerReceived(app.inputPower, antennaHPGain, maximumDistance, app.frequency);
            minimumPowerReceivedmW = minimumPowerReceived * 1e3;
                        
            sampling = 0.01 * (farX+1);
            x = [-farX-1: sampling: farX+1];
            
            a2eLine = app.antenna2entrance * ones(size(x));
            entranceWLine = (app.entranceWidth + app.antenna2entrance) * ones(size(x));
            negativeLine = x(1:find(x==0)) * -1 * slope;
            positiveLine = x(find(x==0):end) * slope;
            [app.FFDomain,app.FFCurve,maximumAperture] = getFFThresholdCurve(x,app.FFThreshold,app.frequency);
            
            plot(app.UIAxes,x,a2eLine);
            plot(app.UIAxes,x,entranceWLine);
            plot(app.UIAxes,x(1:find(x==0)),negativeLine);
            plot(app.UIAxes,x(find(x==0):end),positiveLine);
            plot(app.UIAxes,app.FFDomain,app.FFCurve)
            
            app.MaximumAntennaApertureLengthmEditField.Value = maximumAperture;
            app.MaxDistancewithin3dBrangemEditField.Value = maximumDistance;
            app.MinimumumPowerReceivedmWEditField.Value = minimumPowerReceivedmW;
            app.CloseXmEditField.Value = closeX;
            app.FarXmEditField.Value = farX;
            hold(app.UIAxes,'off');
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
           app.UIAxes.HandleVisibility = 'callback';
           
           cp = get(app.UIAxes,'CurrentPoint');
           x = cp(1,1);
           y = cp(1,2);
           
           distance = sqrt(x^2 + y^2);
           thetaRadians = atan(x/y);
           interpolatedGainDBI = interpolateGain(app.antennaGainDBI,deg2rad(app.hpbwDegrees),abs(thetaRadians));
           antennaGain = 10^(interpolatedGainDBI/10);
           powerReceived = getPowerReceived(app.inputPower, antennaGain, distance, app.frequency);
           
           if (abs(x)<app.FarFieldThresholdmEditField.Value)
               [~,~,idx]=unique(round(abs(app.FFDomain-abs(x))),'stable');
               yMax = app.FFCurve(idx==1)
               if (y<yMax)
                   powerReceived = 0;
               end
           end
           
           app.XmEditField.Value = x;
           app.YmEditField.Value = y;
           app.DistancetoAntennamEditField.Value = distance;
           app.PowerReceivedmWEditField.Value = powerReceived * 10^3;
           app.UIAxes.HandleVisibility = 'off';
        end

        % Value changed function: AntennaToEntrancemEditField
        function AntennaToEntrancemEditFieldValueChanged(app, event)
            app.antenna2entrance = app.AntennaToEntrancemEditField.Value;
        end

        % Value changed function: EntranceWidthmEditField
        function EntranceWidthmEditFieldValueChanged(app, event)
            app.entranceWidth = app.EntranceWidthmEditField.Value;  
        end

        % Value changed function: AntennaGaindBiEditField
        function AntennaGaindBiEditFieldValueChanged(app, event)
            app.antennaGainDBI = app.AntennaGaindBiEditField.Value;
            app.hpbwDegrees = gain2BW(app.antennaGainDBI);
            app.HPBWdegreesEditField.Value = app.hpbwDegrees;
        end

        % Value changed function: MaximumPowerTransmittedWEditField
        function MaximumPowerTransmittedWEditFieldValueChanged(app, event)
            app.inputPower = app.MaximumPowerTransmittedWEditField.Value;
        end

        % Value changed function: FrequencyMHzEditField
        function FrequencyMHzEditFieldValueChanged(app, event)
            app.frequency = (app.FrequencyMHzEditField.Value) * 10^6;
        end

        % Value changed function: FarFieldThresholdmEditField
        function FarFieldThresholdmEditFieldValueChanged(app, event)
            app.FFThreshold = app.FarFieldThresholdmEditField.Value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {473, 473};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {341, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.AntennaToEntrancemEditFieldLabel = uilabel(app.LeftPanel);
            app.AntennaToEntrancemEditFieldLabel.Text = 'Antenna To Entrance (m)';
            app.AntennaToEntrancemEditField = uieditfield(app.LeftPanel, 'numeric');
            app.AntennaToEntrancemEditField.ValueChangedFcn = createCallbackFcn(app, @AntennaToEntrancemEditFieldValueChanged, true);
            app.EntranceWidthmEditFieldLabel = uilabel(app.LeftPanel);
            app.EntranceWidthmEditFieldLabel.Text = 'Entrance Width (m)';
            app.EntranceWidthmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.EntranceWidthmEditField.ValueChangedFcn = createCallbackFcn(app, @EntranceWidthmEditFieldValueChanged, true);
            app.AntennaGaindBiEditFieldLabel = uilabel(app.LeftPanel);
            app.AntennaGaindBiEditFieldLabel.Text = 'Antenna Gain (dBi)';
            app.AntennaGaindBiEditField = uieditfield(app.LeftPanel, 'numeric');
            app.AntennaGaindBiEditField.ValueChangedFcn = createCallbackFcn(app, @AntennaGaindBiEditFieldValueChanged, true);
            app.MaximumPowerTransmittedWEditFieldLabel = uilabel(app.LeftPanel);
            app.MaximumPowerTransmittedWEditFieldLabel.Text = 'Maximum Power Transmitted (W)';
            app.MaximumPowerTransmittedWEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MaximumPowerTransmittedWEditField.ValueChangedFcn = createCallbackFcn(app, @MaximumPowerTransmittedWEditFieldValueChanged, true);
            app.CloseXmEditFieldLabel = uilabel(app.LeftPanel);
            app.CloseXmEditFieldLabel.Text = 'Close X (m)';
            app.CloseXmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FarXmEditFieldLabel = uilabel(app.LeftPanel);
            app.FarXmEditFieldLabel.Text = 'Far X (m)';
            app.FarXmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MinimumPowerReceivedmWEditFieldLabel = uilabel(app.LeftPanel);
            app.MinimumPowerReceivedmWEditFieldLabel.Text = 'Minimum Power Received (mW)';
            app.MinimumumPowerReceivedmWEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ComputeButton = uibutton(app.LeftPanel, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.HPBWdegreesEditFieldLabel = uilabel(app.LeftPanel);
            app.HPBWdegreesEditFieldLabel.Text = 'HPBW (degrees)';
            app.HPBWdegreesEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FrequencyMHzEditFieldLabel = uilabel(app.LeftPanel);
            app.FrequencyMHzEditFieldLabel.Text = 'Frequency (MHz)';
            app.FrequencyMHzEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FrequencyMHzEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyMHzEditFieldValueChanged, true);
            app.MaximumAntennaApertureLengthmEditFieldLabel = uilabel(app.LeftPanel);
            app.MaximumAntennaApertureLengthmEditFieldLabel.Text = 'Maximum Antenna Aperture Length (m)';
            app.MaximumAntennaApertureLengthmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MaxDistancewithin3dBrangemEditFieldLabel = uilabel(app.LeftPanel);
            app.MaxDistancewithin3dBrangemEditFieldLabel.Text = 'Max Distance within 3dB range (m)';
            app.MaxDistancewithin3dBrangemEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FarFieldThresholdmEditFieldLabel = uilabel(app.LeftPanel);
            app.FarFieldThresholdmEditFieldLabel.Text = 'Far Field Threshold (m)';
            app.FarFieldThresholdmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FarFieldThresholdmEditField.ValueChangedFcn = createCallbackFcn(app, @FarFieldThresholdmEditFieldValueChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.PowerReceivedmWLabel = uilabel(app.RightPanel);
            app.PowerReceivedmWLabel.Text = 'Power Received (mW)';
            app.PowerReceivedmWEditField = uieditfield(app.RightPanel, 'numeric');
            app.XmEditFieldLabel = uilabel(app.RightPanel);
            app.XmEditFieldLabel.Text = 'X (m)';
            app.XmEditField = uieditfield(app.RightPanel, 'numeric');
            app.YmEditFieldLabel = uilabel(app.RightPanel);
            app.YmEditFieldLabel.Text = 'Y (m)';
            app.YmEditField = uieditfield(app.RightPanel, 'numeric');
            app.DistancetoAntennamEditFieldLabel = uilabel(app.RightPanel);
            app.DistancetoAntennamEditFieldLabel.Text = 'Distance to Antenna (m)';
            app.DistancetoAntennamEditField = uieditfield(app.RightPanel, 'numeric');
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Entrance Model Illustration')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EntranceModel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end