classdef EntranceModelApplication < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        InputPanel                      matlab.ui.container.Panel
        SystemToEntrancemEditFieldLabel  matlab.ui.control.Label
        SystemToEntrancemEditField      matlab.ui.control.NumericEditField
        EntranceWidthmEditFieldLabel    matlab.ui.control.Label
        EntranceWidthmEditField         matlab.ui.control.NumericEditField
        AntennaGaindBiEditFieldLabel    matlab.ui.control.Label
        AntennaGaindBiEditField         matlab.ui.control.NumericEditField
        PowerTransmittedWEditFieldLabel  matlab.ui.control.Label
        PowerTransmittedWEditField      matlab.ui.control.NumericEditField
        FrequencyMHzEditFieldLabel      matlab.ui.control.Label
        FrequencyMHzEditField           matlab.ui.control.NumericEditField
        FarFieldThresholdmEditFieldLabel  matlab.ui.control.Label
        FarFieldThresholdmEditField     matlab.ui.control.NumericEditField
        ComputeButton                   matlab.ui.control.Button
        SubjectVelocitykmhrEditFieldLabel  matlab.ui.control.Label
        SubjectVelocitykmhrEditField    matlab.ui.control.NumericEditField
        HeightDeltamEditFieldLabel      matlab.ui.control.Label
        HeightDeltamEditField           matlab.ui.control.NumericEditField
        MinimumPowerTransceiveduWLabel  matlab.ui.control.Label
        MinimumPowerTransceiveduWEditField  matlab.ui.control.NumericEditField
        Extend3DGainPlotSwitchLabel     matlab.ui.control.Label
        Extend3DGainPlotSwitch          matlab.ui.control.Switch
        ShowPowerAreaSwitchLabel        matlab.ui.control.Label
        ShowPowerAreaSwitch             matlab.ui.control.Switch
        RadiationPatternSwitchLabel     matlab.ui.control.Label
        RadiationPatternSwitch          matlab.ui.control.Switch
        OutputPanel                     matlab.ui.container.Panel
        CloseRangemEditField            matlab.ui.control.NumericEditField
        Horizontal3dBRangemLabel        matlab.ui.control.Label
        FarRangemEditField              matlab.ui.control.NumericEditField
        MinimumPowerReceiveduWLabel     matlab.ui.control.Label
        MinimumPowerReceiveduWEditField  matlab.ui.control.NumericEditField
        MaximumHPBWdegreesEditFieldLabel  matlab.ui.control.Label
        MaximumHPBWdegreesEditField     matlab.ui.control.NumericEditField
        MaximumApertureSizemLabel       matlab.ui.control.Label
        MaximumApertureSizemEditField   matlab.ui.control.NumericEditField
        MaxDistancewithin3dBrangemEditFieldLabel  matlab.ui.control.Label
        MaxDistancewithin3dBrangemEditField  matlab.ui.control.NumericEditField
        Timein3dBRangesLabel            matlab.ui.control.Label
        MaximumTimeInFieldEditField     matlab.ui.control.NumericEditField
        MinimumTimeInFieldEditField     matlab.ui.control.NumericEditField
        MaximumThetadegreesEditFieldLabel  matlab.ui.control.Label
        MaximumThetadegreesEditField    matlab.ui.control.NumericEditField
        AreaWithinPowerLimitBoundariesm2EditFieldLabel  matlab.ui.control.Label
        AreaWithinPowerLimitBoundariesm2EditField  matlab.ui.control.NumericEditField
        MaximumHorizontalPowerRangemLabel  matlab.ui.control.Label
        MaximumHorizontalPowerRangemEditField  matlab.ui.control.NumericEditField
        PowerAreaPastBoundariesm2EditField_2Label  matlab.ui.control.Label
        PowerAreaPastBoundariesm2EditField  matlab.ui.control.NumericEditField
        PointofInterestPanel            matlab.ui.container.Panel
        XmEditFieldLabel                matlab.ui.control.Label
        XmEditField                     matlab.ui.control.NumericEditField
        YmEditFieldLabel                matlab.ui.control.Label
        YmEditField                     matlab.ui.control.NumericEditField
        PoweratPOImWEditFieldLabel      matlab.ui.control.Label
        PoweratPOImWEditField           matlab.ui.control.NumericEditField
        PowerReceiveduWEditFieldLabel   matlab.ui.control.Label
        PowerReceiveduWEditField        matlab.ui.control.NumericEditField
        DistancetoTranspondermEditFieldLabel  matlab.ui.control.Label
        DistancetoTranspondermEditField  matlab.ui.control.NumericEditField
        PhidegreesEditFieldLabel        matlab.ui.control.Label
        PhidegreesEditField             matlab.ui.control.NumericEditField
        ThetadegreesEditFieldLabel      matlab.ui.control.Label
        ThetadegreesEditField           matlab.ui.control.NumericEditField
        InterpolatedGaindBiEditField_2Label  matlab.ui.control.Label
        InterpolatedGaindBiEditField    matlab.ui.control.NumericEditField
        ExportDataButton                matlab.ui.control.Button
        AutomateButton                  matlab.ui.control.Button
        UIAxes3D                        matlab.ui.control.UIAxes
        UIAxes2D                        matlab.ui.control.UIAxes
        GainAxes3D                      matlab.ui.control.UIAxes
        GainAxes2D                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
        minimumRFIDFrequencyMHz = 902;
        maximumRFIDFrequencyMHz = 928;
        
        entranceWidth = 1.5;
        system2entrance = 0;
        entranceEndPoint = 0;
        inputPower = 1;
        frequency = 902e6;
        FFThreshold = 0.5;
        subjectVelocity = 5;
        heightDelta = 0;
        minimumPoweruW = 1;
        
        antennaGainDBI = 0;
        
        maximumHPBWDegrees = 0;
        maximumThetaDegrees = 0;
        
        closeRange = 0;
        farRange = 0;
        maximum3dBDistance = 0;
        
        %% true or false variables
        is2beamPattern = false;
        extendPlot = false;
        showPowerArea = false;
        
        interpolatedGainCurveColor = "yellow";
        projectedGainCurveColor = "blue";
        
        interpolatedGainPointColor = "cx";
        projectedGainPointColor = "rx";
        
        domain2D = linspace(-5,5);
        range2D = linspace(0,1);
        
        a2eLine;
        entranceWLine;
        HPFieldOfView;
        FFDomain;
        FFCurve;
        FFLine;
        
        powerCurveDomain =[];
        powerCurve = [];
        
        powerAreaDomain = [];
        powerArea = [];
        
        ReceiverXCoordinates = [-0.25 -0.25 0.25 0.25];
        ReceiverYCoordinates = [0 0 0 0];
        ReceiverZCoordinates = [-0.25 0.25 0.25 -0.25];
        
        TagXCoordinates = [-0.05 -0.05 0.05 0.05];
        TagYCoordinates = [0 0 0 0];
        TagZCoordinates = [-0.05 0.05 0.05 -0.05];
        
        DetectedTagColor = "green";
        NotDetectedTagColor = "cyan";
        ReceiverColor = "red";
        
        receiver = 0;
        tag = 0;
        
        ExcelFileDirectory = "data";
        ExcelFileName = "Antenna_Analysis.xlsx";
        ExcelDataSheetName = "Data";
        ExcelFileHeaders = {'System To Entrance (m)', 'Entrance Width (m)', 'Height Delta (m)',...
            'Antenna Gain (dBi)', 'Power Transmitted (W)', 'Frequency (MHz)', 'Far Field Threshold (m)',...
            'Subject Velocity (km/hr)', 'Minimum Power Transceived (uW)', 'Radiation Pattern', 'Maximum HPBW (degrees)',...
            'Maximum Theta (degrees)', 'Minimum Horizontal 3dB Range (m)', 'Maximum Horizontal 3dB Range (m)',...
            'Minimum Time in 3dB Range (s)', 'Maximum Time in 3dB Range (s)',...
            'Maximum Distance Within 3dB Range (m)', 'Minimum Power Received (uW)',...
            'Maximum Aperature Size (m)', 'Area Within Power Limit & Boundaries (m^2)',...
            'Power Area Past Boundaries (m^2)', 'Maximum Horizontal Power Range (m)'};
        
        currentData = [];
        isAutomating = false;
        
    end
    
    methods (Access = private)
        
        function plotSetupCurves(app)
            % Plot the setup lines
            cla(app.UIAxes2D) 
            hold(app.UIAxes2D,'on')
            plot(app.UIAxes2D, app.domain2D,                app.a2eLine,        "DisplayName",'Antenna To Entrance');
            plot(app.UIAxes2D, app.domain2D,                app.entranceWLine,  "DisplayName",'Entrance Width');
            plot(app.UIAxes2D, app.domain2D,                app.HPFieldOfView,   "DisplayName",'3dB Field Of View');
            plot(app.UIAxes2D, app.domain2D,                app.FFLine,         "DisplayName",'Far-Field Tangent')
            plot(app.UIAxes2D, app.FFDomain,                app.FFCurve,        "DisplayName",'Far-Field Radius')
            %legend(app.UIAxes2D);
            ylim(app.UIAxes2D, [-0.5 app.entranceEndPoint + 1]);
            hold(app.UIAxes2D,'off')
            
            plotMinimumPowerReceivedArc(app)
        end
        
        function plotMinimumPowerReceivedArc(app)
            app.powerCurveDomain =[];
            app.powerCurve = [];
            
            app.powerAreaDomain = [];
            app.powerArea = [];
            
            for xIndex = 1 : length(app.domain2D)
                x = app.domain2D(xIndex);
                
                for yIndex = 1: length(app.range2D)
                    y = app.range2D(yIndex);
                    
                    phiRadians = getPhiRadians(app, x, y);
                    thetaRadians = getThetaRadians(app, x, y, app.heightDelta);
                    xyDistance = sqrt(x^2 + y^2);
                    distance = sqrt(xyDistance^2 + app.heightDelta^2);
                    
                    interpolatedThetaGainDBI = interpolateGain(app.antennaGainDBI, deg2rad(app.maximumHPBWDegrees), abs(thetaRadians));
                    interpolatedGainDBI = interpolateGain(interpolatedThetaGainDBI, deg2rad(app.maximumHPBWDegrees), abs(phiRadians));
                    
                    if (interpolatedGainDBI < (app.antennaGainDBI - 3))
                        continue;
                    end
                    
                    interpolatedGain = 10^(interpolatedGainDBI/10);
                    
                    [~, powerReceived] = getPowerReceived(app.inputPower, interpolatedGain, distance, app.frequency, thetaRadians, app.FFThreshold);
                        
                    powerReceiveduW = powerReceived*1e6;
                    
                    if (powerReceiveduW > app.minimumPoweruW)
                        app.powerAreaDomain = [app.powerAreaDomain x];
                        app.powerArea = [app.powerArea y];
                    end
                end
            end
            
            % Get the boundary of the power area to create the power curve
            % boundary
            app.powerCurveDomain = unique(app.powerAreaDomain);
            for x = app.powerCurveDomain
                indices = find(app.powerAreaDomain == x);
                yMax = max(app.powerArea(indices));
                app.powerCurve = [app.powerCurve yMax];
            end
            
            hold(app.UIAxes2D,'on')
            if app.showPowerArea
               plot(app.UIAxes2D, app.powerAreaDomain, app.powerArea, "DisplayName",'Minimum Power Area')
            else
                plot(app.UIAxes2D, app.powerCurveDomain, app.powerCurve, "DisplayName",'Constant Power Curve')
            end
            hold(app.UIAxes2D,'off')
            
            if ~isempty(app.powerCurveDomain)
                app.MaximumHorizontalPowerRangemEditField.Value = app.powerCurveDomain(end);
            else
                app.MaximumHorizontalPowerRangemEditField.Value = 0;
            end
        end
        
        function plot3DRadiationPattern(app)
            alpha = app.maximumHPBWDegrees/2;
            phi = linspace(-alpha,alpha);
            theta = linspace(-alpha,alpha);
            [P,T] = meshgrid(phi,theta);
            G = mapGain(app,mapGain(app, app.antennaGainDBI, P), T);

            if (~app.extendPlot)
                G(G<(app.antennaGainDBI-3)) = nan;
            end
            
            cla(app.GainAxes3D);
            hold(app.GainAxes3D,'on')
            xlabel(app.GainAxes3D, 'Phi (degrees)');
            ylabel(app.GainAxes3D, 'Theta (degrees)');
            zlabel(app.GainAxes3D, 'Gain (dBi)');
            surf(app.GainAxes3D, P, T, G);
            view(app.GainAxes3D, 3);
            hold(app.GainAxes3D,'off');
        end
        
        function plotGainContour(app, thetaRadians, interpolatedThetaGainDBI)
            alpha = app.maximumHPBWDegrees/2;
            phi = linspace(-alpha,alpha);
            projectedGain = mapGain(app, interpolatedThetaGainDBI, phi);
            cla(app.GainAxes2D);
            hold(app.GainAxes2D,'on');
            %legend(app.GainAxes2D);
            title(app.GainAxes2D, sprintf('Gain Contour at Theta = %.2f degrees', rad2deg(thetaRadians)));
            plot(app.GainAxes2D, phi, projectedGain,                                                                        "DisplayName",'Projected Gain', "Color", app.projectedGainCurveColor);
            line(app.GainAxes2D,[-alpha 0 alpha], [interpolatedThetaGainDBI-3 interpolatedThetaGainDBI interpolatedThetaGainDBI-3],     "DisplayName",'Interpolated Gain', "Color", app.interpolatedGainCurveColor);
            hold(app.GainAxes2D,'off');
        end
        
        function out = mapGain(app,maximumGain, in)
            alpha = app.maximumHPBWDegrees/2;
            out = 3.*cos((pi/2)/deg2rad(alpha) .* deg2rad(in)) + (maximumGain-3);
        end
        
        function ms = KMHR2MS(~, kmhr)
            ms = kmhr / 3.6;
        end
        
        function phiRadians = getPhiRadians(~, x, y)
            phiRadians = atan(x/y);
        end
        
        function thetaRadians = getThetaRadians(~, x, y, z)
            xyDistance = sqrt(x^2 + y^2);
            thetaRadians = atan(z/xyDistance);
        end
        
        function getPowerAreaWithinBounds(app)
            totalArea = 0;
            completeArea = 0;
            
            uniqXValues = unique(app.powerAreaDomain);

            start = uniqXValues(1);
            finish = uniqXValues(2);
            rectangleWidth = finish-start;
            
            for uniqXVal = uniqXValues
                indices = find(app.powerAreaDomain == uniqXVal);
                yValues = app.powerArea(indices);
                
                yStart = max(min(yValues),app.system2entrance);
                yFinish = min(max(yValues),app.entranceEndPoint);
                
                if yStart > yFinish
                    height = 0;
                else
                    height = yFinish - yStart;
                end
                
                yFull = max(yValues);
                
                
                fullHeight = yFull - yStart;
                
                area = height * rectangleWidth;
                fullArea = fullHeight * rectangleWidth;
                
                completeArea = completeArea + fullArea;
                totalArea = totalArea + area;
            end
            
            app.AreaWithinPowerLimitBoundariesm2EditField.Value = totalArea;
            
            totalExtraArea = completeArea - totalArea;
            
            app.PowerAreaPastBoundariesm2EditField.Value = totalExtraArea;
        end
        
        function saveData(app)
            %%Write to excel file
            currentDirectory = pwd;
            folderDirectory = fullfile(currentDirectory, app.ExcelFileDirectory);
            fileDirectory = fullfile(folderDirectory, app.ExcelFileName);
            
            if ~exist(folderDirectory, 'dir')
                mkdir(folderDirectory)
            end
            
            if ~isfile(fileDirectory)
                writecell(app.ExcelFileHeaders,fileDirectory,'Sheet',app.ExcelDataSheetName);
            end
            
            T = readtable(fileDirectory,'ReadVariableNames',true,'VariableNamingRule','preserve');
            
            allData = [T; app.currentData];
            writetable(allData,fileDirectory,'Sheet',app.ExcelDataSheetName);
            app.currentData = [];
        end
    end
       

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SystemToEntrancemEditField
        function SystemToEntrancemEditFieldValueChanged(app, event)
            app.system2entrance = app.SystemToEntrancemEditField.Value;
        end

        % Value changed function: EntranceWidthmEditField
        function EntranceWidthmEditFieldValueChanged(app, event)
            app.entranceWidth = app.EntranceWidthmEditField.Value;
        end

        % Value changed function: AntennaGaindBiEditField
        function AntennaGaindBiEditFieldValueChanged(app, event)
            app.antennaGainDBI = app.AntennaGaindBiEditField.Value;
        end

        % Value changed function: PowerTransmittedWEditField
        function PowerTransmittedWEditFieldValueChanged(app, event)
            app.inputPower = app.PowerTransmittedWEditField.Value;
        end

        % Value changed function: FrequencyMHzEditField
        function FrequencyMHzEditFieldValueChanged(app, event)
            app.frequency = (app.FrequencyMHzEditField.Value) * 10^6;
        end

        % Value changed function: FarFieldThresholdmEditField
        function FarFieldThresholdmEditFieldValueChanged(app, event)
            app.FFThreshold = app.FarFieldThresholdmEditField.Value;
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            % initialize setup
            app.entranceEndPoint = app.entranceWidth + app.system2entrance;
            app.range2D = linspace(0,app.entranceEndPoint + 2, round(100*app.entranceEndPoint));
            
            app.maximumHPBWDegrees = gain2BW(app.antennaGainDBI, app.is2beamPattern);
            app.MaximumHPBWdegreesEditField.Value = app.maximumHPBWDegrees;
            
            app.maximumThetaDegrees = rad2deg(atan(app.heightDelta/app.system2entrance));
            
            if (app.maximumThetaDegrees == 90 || isnan(app.maximumThetaDegrees))
                app.maximumThetaDegrees = 0;
            end
            
            app.MaximumThetadegreesEditField.Value = app.maximumThetaDegrees;
            
            thetaGain = mapGain(app, app.antennaGainDBI, app.maximumThetaDegrees);
            alpha = app.maximumHPBWDegrees / 2;
            alphaRadians = deg2rad(alpha);
            
            %betaRadians = alphaRadians / (pi/2) * (acos((app.antennaGainDBI-thetaGain)/3));
            %slope = cot(betaRadians);
            slope = cot(alphaRadians);
            
            plot3DRadiationPattern(app);
            
            app.closeRange = app.system2entrance / slope;
            app.farRange = app.entranceEndPoint / slope;
            app.maximum3dBDistance = sqrt(app.entranceEndPoint^2 + app.farRange^2);
            
            app.domain2D = linspace(-app.farRange-1,app.farRange+1, max(100, 100 * (app.farRange+1)));

            app.MaxDistancewithin3dBrangemEditField.Value = app.maximum3dBDistance;
            app.FarRangemEditField.Value = app.farRange;
            app.MaximumTimeInFieldEditField.Value = 2 * app.farRange / KMHR2MS(app, app.subjectVelocity);
            
            % Plot the setup lines                        
            app.a2eLine = app.system2entrance * ones(size(app.domain2D));
            app.entranceWLine = app.entranceEndPoint * ones(size(app.domain2D));
            
            [~, zeroIndex] = min(abs(app.domain2D));
            app.HPFieldOfView = app.domain2D;
            app.HPFieldOfView(1:zeroIndex) = app.domain2D(1:zeroIndex) * -1 * slope;
            app.HPFieldOfView(zeroIndex: end) = app.domain2D(zeroIndex:end) * slope;
            
            % Plot the Far Field threshold curve
            [app.FFDomain, app.FFCurve] = getConstantRadiusArc(app.domain2D, app.FFThreshold);
            app.FFLine = app.FFThreshold * ones(size(app.domain2D));
            
            app.closeRange = max(app.FFThreshold / slope, app.closeRange);
            app.CloseRangemEditField.Value = app.closeRange;
            app.MinimumTimeInFieldEditField.Value = 2 * app.closeRange / KMHR2MS(app, app.subjectVelocity);
            
            plotSetupCurves(app);
            
            % Plot 3D illustration
            cla(app.UIAxes3D) 
            hold(app.UIAxes3D,'on');
            app.receiver = patch(app.UIAxes3D, app.ReceiverXCoordinates, app.ReceiverYCoordinates, app.ReceiverZCoordinates, app.ReceiverColor);
            app.tag = patch(app.UIAxes3D, app.TagXCoordinates + app.farRange, app.TagYCoordinates + app.entranceEndPoint, app.TagZCoordinates, app.NotDetectedTagColor);
            hold(app.UIAxes3D,'off');
            
            % Calculate power at Farthest 3db point
            antennaGain = 10^((app.antennaGainDBI - 3)/10);
            thetaRadians = getThetaRadians(app, app.farRange, app.entranceEndPoint, app.heightDelta);
            distance = sqrt(app.maximum3dBDistance^2 + app.heightDelta^2);
        
            [~, minimumPowerReceived] = getPowerReceived(app.inputPower, antennaGain, distance, app.frequency, thetaRadians, app.FFThreshold);
            minimumPowerReceiveduW = minimumPowerReceived * 1e6;
            
            app.MinimumPowerReceiveduWEditField.Value = minimumPowerReceiveduW;
            
            app.MaximumApertureSizemEditField.Value = getMaximumAperture(app.FFThreshold, app.frequency);
            
            app.getPowerAreaWithinBounds();
            
        end

        % Button down function: UIAxes2D
        function UIAxes2DButtonDown(app, event)
            %Find where the user clicked on the figure & Plot on 2D axis
            cp = get(app.UIAxes2D,'CurrentPoint');
            x = cp(1,1);
            y = cp(1,2);
            
            phiRadians = getPhiRadians(app,x,y);
            thetaRadians = getThetaRadians(app, x , y, app.heightDelta);
            xyDistance = sqrt(x^2 + y^2);
            distance = sqrt(xyDistance^2 + app.heightDelta^2);
            
            plotSetupCurves(app);
            hold(app.UIAxes2D,'on');
            try
                [TDomain, TCurve] = getConstantRadiusArc(app.domain2D, xyDistance);
                plot(app.UIAxes2D, TDomain, TCurve,"DisplayName",'Constant Distance')
            catch
            end
            plot(app.UIAxes2D, x,y,'r*', "DisplayName",'Point of Interest');
            hold(app.UIAxes2D,'off');
            
            interpolatedThetaGainDBI = interpolateGain(app.antennaGainDBI, deg2rad(app.maximumHPBWDegrees), abs(thetaRadians));
            interpolatedGainDBI = interpolateGain(interpolatedThetaGainDBI, deg2rad(app.maximumHPBWDegrees), abs(phiRadians));
            
            if (interpolatedGainDBI > (app.antennaGainDBI - 3))
                % Plot the sliced gain contour
                plotGainContour(app, thetaRadians, interpolatedThetaGainDBI);
                hold(app.GainAxes2D,'on');
                plot(app.GainAxes2D, rad2deg(phiRadians), interpolatedGainDBI,                                          app.interpolatedGainPointColor, "DisplayName",'POI Interpolated Gain');
                plot(app.GainAxes2D, rad2deg(phiRadians), mapGain(app, interpolatedThetaGainDBI, rad2deg(phiRadians)),  app.projectedGainPointColor,    "DisplayName",'POI Projected Gain');
                hold(app.GainAxes2D,'off');     
            else
                cla(app.GainAxes2D);
                interpolatedGainDBI = 0;
            end
            
            antennaGain = 10^(interpolatedGainDBI/10);
            
            [powerAtPOI, powerReceived] = getPowerReceived(app.inputPower, antennaGain, distance, app.frequency, thetaRadians, app.FFThreshold);
            
            powerAtPOImW = powerAtPOI * 10^3;
            powerReceiveduW = powerReceived * 10^6;
            
            %Represent it on the 3D axis
            
            if powerReceiveduW > app.minimumPoweruW
                tagColor = app.DetectedTagColor;
            else
                tagColor = app.NotDetectedTagColor;
            end
            
            cla(app.UIAxes3D) 
            hold(app.UIAxes3D,'on')
            
            app.receiver = patch(app.UIAxes3D, app.ReceiverXCoordinates, app.ReceiverYCoordinates, app.ReceiverZCoordinates, app.ReceiverColor);
            app.tag = patch(app.UIAxes3D, app.TagXCoordinates + x, app.TagYCoordinates + y, app.TagZCoordinates + app.heightDelta, tagColor);
            
            hold(app.UIAxes3D,'off');
            
            app.XmEditField.Value = x;
            app.YmEditField.Value = y;
            app.DistancetoTranspondermEditField.Value = distance;
            app.PoweratPOImWEditField.Value = powerAtPOImW;
            app.PowerReceiveduWEditField.Value = powerReceiveduW;
            app.PhidegreesEditField.Value = rad2deg(phiRadians);
            app.ThetadegreesEditField.Value = rad2deg(thetaRadians);
            app.InterpolatedGaindBiEditField.Value = interpolatedGainDBI;
        end

        % Value changed function: HeightDeltamEditField
        function HeightDeltamEditFieldValueChanged(app, event)
            app.heightDelta = app.HeightDeltamEditField.Value;
        end

        % Value changed function: SubjectVelocitykmhrEditField
        function SubjectVelocitykmhrEditFieldValueChanged(app, event)
            app.subjectVelocity = app.SubjectVelocitykmhrEditField.Value;            
        end

        % Value changed function: MinimumPowerTransceiveduWEditField
        function MinimumPowerTransceiveduWEditFieldValueChanged(app, event)
            app.minimumPoweruW = app.MinimumPowerTransceiveduWEditField.Value;
        end

        % Value changed function: ShowPowerAreaSwitch
        function ShowPowerAreaSwitchValueChanged(app, event)
            app.showPowerArea = (app.ShowPowerAreaSwitch.Value == "On");
        end

        % Value changed function: Extend3DGainPlotSwitch
        function Extend3DGainPlotSwitchValueChanged(app, event)
            app.extendPlot = (app.Extend3DGainPlotSwitch.Value == "On");
        end

        % Value changed function: RadiationPatternSwitch
        function BeamPatternSwitchValueChanged(app, event)
            app.is2beamPattern = (app.RadiationPatternSwitch.Value == "2-beams");
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            applicationDataObjects = {app.system2entrance, app.entranceWidth, app.heightDelta,...
                app.antennaGainDBI, app.inputPower, app.FrequencyMHzEditField.Value, app.FFThreshold, app.subjectVelocity,...
                app.minimumPoweruW, app.maximumHPBWDegrees, app.maximumThetaDegrees, app.closeRange,...
                app.farRange, app.MinimumTimeInFieldEditField.Value, app.MaximumTimeInFieldEditField.Value,...
                app.maximum3dBDistance, app.MinimumPowerReceiveduWEditField.Value, ...
                app.MaximumApertureSizemEditField.Value, app.AreaWithinPowerLimitBoundariesm2EditField.Value,...
                app.MaximumHorizontalPowerRangemEditField.Value};
            
            
            newData = table(app.system2entrance, app.entranceWidth, app.heightDelta,...
                app.antennaGainDBI, app.inputPower, app.FrequencyMHzEditField.Value, app.FFThreshold, app.subjectVelocity,...
                app.minimumPoweruW, convertCharsToStrings(app.RadiationPatternSwitch.Value) ,app.maximumHPBWDegrees, app.maximumThetaDegrees, app.closeRange,...
                app.farRange, app.MinimumTimeInFieldEditField.Value, app.MaximumTimeInFieldEditField.Value,...
                app.maximum3dBDistance, app.MinimumPowerReceiveduWEditField.Value, ...
                app.MaximumApertureSizemEditField.Value, app.AreaWithinPowerLimitBoundariesm2EditField.Value,...
                app.PowerAreaPastBoundariesm2EditField.Value, app.MaximumHorizontalPowerRangemEditField.Value,...
                'VariableNames', app.ExcelFileHeaders);
            
            if ~isempty(app.currentData)
                app.currentData = [app.currentData; newData];
            else
                app.currentData = newData;
            end
            
            if ~app.isAutomating
                saveData(app);                
            end
        end

        % Button pushed function: AutomateButton
        function AutomateButtonPushed(app, event)
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                'Message','Collecting Data','Indeterminate','on');
    
            antennaGains = [5,6,7,8,9,10,11,12,13];
            radiationPatterns = ["1-beam", "2-beams"];
            heightDeltas = [0, 0.25, 0.5];
            farFieldThresholds = [0,0.25,0.5];
            frequencySweep = linspace(app.minimumRFIDFrequencyMHz, app.maximumRFIDFrequencyMHz, 27);
            minimumPowerSweep = linspace(1,20,20);
            app.isAutomating = true;
            
            %Sweep different antenna gains
            for antennaGain = antennaGains
                app.AntennaGaindBiEditField.Value = antennaGain;
                AntennaGaindBiEditFieldValueChanged(app,event);
                
                %Sweep different radiation patterns
                for radiationPattern = radiationPatterns
                    app.RadiationPatternSwitch.Value = radiationPattern;
                    BeamPatternSwitchValueChanged(app,event);
                    
                    %Sweep different heights
%                     for hDelta = heightDeltas
%                         app.HeightDeltamEditField.Value = hDelta;
%                         HeightDeltamEditFieldValueChanged(app,event);
                        
                        %Sweep different far field thresholds
%                         for ffThreshold = farFieldThresholds
%                             app.FarFieldThresholdmEditField.Value = ffThreshold;
%                             FarFieldThresholdmEditFieldValueChanged(app,event);
                            
                            %Sweep the frequencies
                            for freq = frequencySweep
                                app.FrequencyMHzEditField.Value = freq;
                                FrequencyMHzEditFieldValueChanged(app, event)
                                
                                %Sweep power transceived requirements
                                for minimumPower = minimumPowerSweep
                                    app.MinimumPowerTransceiveduWEditField.Value = minimumPower;
                                    MinimumPowerTransceiveduWEditFieldValueChanged(app,event);
                                    
                                    %Run program and export data
                                    ComputeButtonPushed(app,event);
                                    ExportDataButtonPushed(app,event);
                                end
                            end
                        %end
                    %end
                    
                    saveData(app);
                end
            end
            app.isAutomating = false;
            close(d);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InputPanel = uipanel(app.UIFigure);
            app.InputPanel.Title = 'Input Panel';
            app.SystemToEntrancemEditFieldLabel = uilabel(app.InputPanel);
            app.SystemToEntrancemEditFieldLabel.Text = 'System To Entrance (m)';
            app.SystemToEntrancemEditField = uieditfield(app.InputPanel, 'numeric');
            app.SystemToEntrancemEditField.ValueChangedFcn = createCallbackFcn(app, @SystemToEntrancemEditFieldValueChanged, true);
            app.EntranceWidthmEditFieldLabel = uilabel(app.InputPanel);
            app.EntranceWidthmEditFieldLabel.Text = 'Entrance Width (m)';
            app.EntranceWidthmEditField = uieditfield(app.InputPanel, 'numeric');
            app.EntranceWidthmEditField.ValueChangedFcn = createCallbackFcn(app, @EntranceWidthmEditFieldValueChanged, true);
            app.EntranceWidthmEditField.Value = 1.5;
            app.AntennaGaindBiEditFieldLabel = uilabel(app.InputPanel);
            app.AntennaGaindBiEditFieldLabel.Text = 'Antenna Gain (dBi)';
            app.AntennaGaindBiEditField = uieditfield(app.InputPanel, 'numeric');
            app.AntennaGaindBiEditField.ValueChangedFcn = createCallbackFcn(app, @AntennaGaindBiEditFieldValueChanged, true);
            app.PowerTransmittedWEditFieldLabel = uilabel(app.InputPanel);
            app.PowerTransmittedWEditFieldLabel.Text = 'Power Transmitted (W)';
            app.PowerTransmittedWEditField = uieditfield(app.InputPanel, 'numeric');
            app.PowerTransmittedWEditField.ValueChangedFcn = createCallbackFcn(app, @PowerTransmittedWEditFieldValueChanged, true);
            app.PowerTransmittedWEditField.Value = 1;
            app.FrequencyMHzEditFieldLabel = uilabel(app.InputPanel);
            app.FrequencyMHzEditFieldLabel.Text = 'Frequency (MHz)';
            app.FrequencyMHzEditField = uieditfield(app.InputPanel, 'numeric');
            app.FrequencyMHzEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyMHzEditFieldValueChanged, true);
            app.FrequencyMHzEditField.Value = 902;
            app.FarFieldThresholdmEditFieldLabel = uilabel(app.InputPanel);
            app.FarFieldThresholdmEditFieldLabel.Text = 'Far Field Threshold (m)';
            app.FarFieldThresholdmEditField = uieditfield(app.InputPanel, 'numeric');
            app.FarFieldThresholdmEditField.ValueChangedFcn = createCallbackFcn(app, @FarFieldThresholdmEditFieldValueChanged, true);
            app.FarFieldThresholdmEditField.Value = 0.5;
            app.ComputeButton = uibutton(app.InputPanel, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.SubjectVelocitykmhrEditFieldLabel = uilabel(app.InputPanel);
            app.SubjectVelocitykmhrEditFieldLabel.Text = 'Subject Velocity (km/hr)';
            app.SubjectVelocitykmhrEditField = uieditfield(app.InputPanel, 'numeric');
            app.SubjectVelocitykmhrEditField.ValueChangedFcn = createCallbackFcn(app, @SubjectVelocitykmhrEditFieldValueChanged, true);
            app.SubjectVelocitykmhrEditField.Value = 5;
            app.HeightDeltamEditFieldLabel = uilabel(app.InputPanel);
            app.HeightDeltamEditFieldLabel.Text = 'Height Delta (m)';
            app.HeightDeltamEditField = uieditfield(app.InputPanel, 'numeric');
            app.HeightDeltamEditField.ValueChangedFcn = createCallbackFcn(app, @HeightDeltamEditFieldValueChanged, true);
            app.MinimumPowerTransceiveduWLabel = uilabel(app.InputPanel);
            app.MinimumPowerTransceiveduWLabel.Text = 'Minimum Power Transceived(uW)';
            app.MinimumPowerTransceiveduWEditField = uieditfield(app.InputPanel, 'numeric');
            app.MinimumPowerTransceiveduWEditField.ValueChangedFcn = createCallbackFcn(app, @MinimumPowerTransceiveduWEditFieldValueChanged, true);
            app.MinimumPowerTransceiveduWEditField.Value = 1;
            app.Extend3DGainPlotSwitchLabel = uilabel(app.InputPanel);
            app.Extend3DGainPlotSwitchLabel.Text = 'Extend 3D Gain Plot';
            app.Extend3DGainPlotSwitch = uiswitch(app.InputPanel, 'slider');
            app.Extend3DGainPlotSwitch.ValueChangedFcn = createCallbackFcn(app, @Extend3DGainPlotSwitchValueChanged, true);
            app.ShowPowerAreaSwitchLabel = uilabel(app.InputPanel);
            app.ShowPowerAreaSwitchLabel.Text = 'Show Power Area';
            app.ShowPowerAreaSwitch = uiswitch(app.InputPanel, 'slider');
            app.ShowPowerAreaSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowPowerAreaSwitchValueChanged, true);
            app.RadiationPatternSwitchLabel = uilabel(app.InputPanel);
            app.RadiationPatternSwitchLabel.Text = 'Radiation Pattern';
            app.RadiationPatternSwitch = uiswitch(app.InputPanel, 'slider');
            app.RadiationPatternSwitch.Items = {'2-beams', '1-beam'};
            app.RadiationPatternSwitch.ValueChangedFcn = createCallbackFcn(app, @BeamPatternSwitchValueChanged, true);
            app.RadiationPatternSwitch.Value = '1-beam';
            app.OutputPanel = uipanel(app.UIFigure);
            app.OutputPanel.Title = 'Output Panel';
            app.CloseRangemEditField = uieditfield(app.OutputPanel, 'numeric');
            app.Horizontal3dBRangemLabel = uilabel(app.OutputPanel);
            app.Horizontal3dBRangemLabel.Text = '< Horizontal 3dB Range (m) <';
            app.FarRangemEditField = uieditfield(app.OutputPanel, 'numeric');
            app.MinimumPowerReceiveduWLabel = uilabel(app.OutputPanel);
            app.MinimumPowerReceiveduWLabel.Text = 'Minimum Power Received (uW)';
            app.MinimumPowerReceiveduWEditField = uieditfield(app.OutputPanel, 'numeric');
            app.MaximumHPBWdegreesEditFieldLabel = uilabel(app.OutputPanel);
            app.MaximumHPBWdegreesEditFieldLabel.Text = ' Maximum HPBW (degrees)';
            app.MaximumHPBWdegreesEditField = uieditfield(app.OutputPanel, 'numeric');
            app.MaximumApertureSizemLabel = uilabel(app.OutputPanel);
            app.MaximumApertureSizemLabel.Text = 'Maximum Aperture Size (m)';
            app.MaximumApertureSizemEditField = uieditfield(app.OutputPanel, 'numeric');
            app.MaxDistancewithin3dBrangemEditFieldLabel = uilabel(app.OutputPanel);
            app.MaxDistancewithin3dBrangemEditFieldLabel.Text = 'Max Distance within 3dB range (m)';
            app.MaxDistancewithin3dBrangemEditField = uieditfield(app.OutputPanel, 'numeric');
            app.Timein3dBRangesLabel = uilabel(app.OutputPanel);
            app.Timein3dBRangesLabel.Text = '< Time in 3dB Range (s) < ';
            app.MaximumTimeInFieldEditField = uieditfield(app.OutputPanel, 'numeric');
            app.MinimumTimeInFieldEditField = uieditfield(app.OutputPanel, 'numeric');
            app.MaximumThetadegreesEditFieldLabel = uilabel(app.OutputPanel);
            app.MaximumThetadegreesEditFieldLabel.Text = 'Maximum Theta (degrees)';
            app.MaximumThetadegreesEditField = uieditfield(app.OutputPanel, 'numeric');
            app.AreaWithinPowerLimitBoundariesm2EditFieldLabel = uilabel(app.OutputPanel);
            app.AreaWithinPowerLimitBoundariesm2EditFieldLabel.Text = 'Area Within Power Limit & Boundaries (m^2)';
            app.AreaWithinPowerLimitBoundariesm2EditField = uieditfield(app.OutputPanel, 'numeric');
            app.MaximumHorizontalPowerRangemLabel = uilabel(app.OutputPanel);
            app.MaximumHorizontalPowerRangemLabel.Text = 'Maximum Horizontal Power Range (m)';
            app.MaximumHorizontalPowerRangemEditField = uieditfield(app.OutputPanel, 'numeric');
            app.PowerAreaPastBoundariesm2EditField_2Label = uilabel(app.OutputPanel);
            app.PowerAreaPastBoundariesm2EditField_2Label.Text = 'Power Area Past Boundaries (m^2)';
            app.PowerAreaPastBoundariesm2EditField = uieditfield(app.OutputPanel, 'numeric');
            app.PointofInterestPanel = uipanel(app.UIFigure);
            app.PointofInterestPanel.Title = 'Point of Interest Panel';
            app.XmEditFieldLabel = uilabel(app.PointofInterestPanel);
            app.XmEditFieldLabel.Text = 'X (m)';
            app.XmEditField = uieditfield(app.PointofInterestPanel, 'numeric');
            app.YmEditFieldLabel = uilabel(app.PointofInterestPanel);
            app.YmEditFieldLabel.Text = 'Y (m)';
            app.YmEditField = uieditfield(app.PointofInterestPanel, 'numeric');
            app.PoweratPOImWEditFieldLabel = uilabel(app.PointofInterestPanel);
            app.PoweratPOImWEditFieldLabel.Text = 'Power at POI (mW)';
            app.PoweratPOImWEditField = uieditfield(app.PointofInterestPanel, 'numeric');
            app.PowerReceiveduWEditFieldLabel = uilabel(app.PointofInterestPanel);
            app.PowerReceiveduWEditFieldLabel.Text = 'Power Received (uW)';
            app.PowerReceiveduWEditField = uieditfield(app.PointofInterestPanel, 'numeric');
            app.DistancetoTranspondermEditFieldLabel = uilabel(app.PointofInterestPanel);
            app.DistancetoTranspondermEditFieldLabel.Text = 'Distance to Transponder (m)';
            app.DistancetoTranspondermEditField = uieditfield(app.PointofInterestPanel, 'numeric');
            app.PhidegreesEditFieldLabel = uilabel(app.PointofInterestPanel);
            app.PhidegreesEditFieldLabel.Text = 'Phi (degrees)';
            app.PhidegreesEditField = uieditfield(app.PointofInterestPanel, 'numeric');
            app.ThetadegreesEditFieldLabel = uilabel(app.PointofInterestPanel);
            app.ThetadegreesEditFieldLabel.Text = 'Theta (degrees)';
            app.ThetadegreesEditField = uieditfield(app.PointofInterestPanel, 'numeric');
            app.InterpolatedGaindBiEditField_2Label = uilabel(app.PointofInterestPanel);
            app.InterpolatedGaindBiEditField_2Label.Text = 'Interpolated Gain (dBi)';
            app.InterpolatedGaindBiEditField = uieditfield(app.PointofInterestPanel, 'numeric');
            app.ExportDataButton = uibutton(app.PointofInterestPanel, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            app.AutomateButton = uibutton(app.PointofInterestPanel, 'push');
            app.AutomateButton.ButtonPushedFcn = createCallbackFcn(app, @AutomateButtonPushed, true);
            app.AutomateButton.Text = 'Automate';
            app.UIAxes3D = uiaxes(app.UIFigure);
            title(app.UIAxes3D, 'Reader-Tag 3D View')
            xlabel(app.UIAxes3D, 'X')
            ylabel(app.UIAxes3D, 'Y')
            zlabel(app.UIAxes3D, 'Z')
            app.UIAxes2D = uiaxes(app.UIFigure);
            title(app.UIAxes2D, 'Store Top-View')
            xlabel(app.UIAxes2D, 'X (m)')
            ylabel(app.UIAxes2D, 'Y (m)')
            zlabel(app.UIAxes2D, 'Z')
            app.UIAxes2D.ButtonDownFcn = createCallbackFcn(app, @UIAxes2DButtonDown, true);
            app.GainAxes3D = uiaxes(app.UIFigure);
            title(app.GainAxes3D, '3D Gain View')
            xlabel(app.GainAxes3D, 'Phi (degrees)')
            ylabel(app.GainAxes3D, 'Gain (dBi)')
            zlabel(app.GainAxes3D, 'Z')
            app.GainAxes3D.XTick = [0 0.5 1];
            app.GainAxes2D = uiaxes(app.UIFigure);
            title(app.GainAxes2D, 'Gain Contour')
            xlabel(app.GainAxes2D, 'Phi (degrees)')
            ylabel(app.GainAxes2D, 'Gain (dBi)')
            zlabel(app.GainAxes2D, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EntranceModelApplication

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end